classdef AcquisitorApp < matlab.apps.AppBase
    %ACQUISITORAPP Camera Control software developed in MATLAB for
    %acquiring images.
    %   This app provides functionalities for:
    %   - Configure Andor and Zelux cameras
    %   - Acquire images with a programmable sequence
    %   - Display real-time analysis

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusLamp                      matlab.ui.control.Lamp
        StatusLabel                     matlab.ui.control.Label
        RunNumLabel                     matlab.ui.control.Label
        ElapsedTimeLabel                matlab.ui.control.Label
        TotalRunTimeLabel               matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SettingTab                      matlab.ui.container.Tab
        OperationPanel                  matlab.ui.container.Panel
        ReplaySettingPanel              matlab.ui.container.Panel
        LoadDatasetButton               matlab.ui.control.Button
        DatasetLabel                    matlab.ui.control.Label
        AcquisitionSettingPanel         matlab.ui.container.Panel
        InitializeDatasetStorageButton  matlab.ui.control.Button
        EstimatedDatasetsizeLabel       matlab.ui.control.Label
        RefreshIntervalSpinner          matlab.ui.control.Spinner
        PoolingintervalsLabel           matlab.ui.control.Label
        AcquisitiontimeoutsSpinner      matlab.ui.control.Spinner
        AcquisitiontimeoutsSpinnerLabel  matlab.ui.control.Label
        NumberofacquisitionsSpinner     matlab.ui.control.Spinner
        NumberofacquisitionsSpinnerLabel  matlab.ui.control.Label
        OperationModeButtonGroup        matlab.ui.container.ButtonGroup
        ReplayModeButton                matlab.ui.control.RadioButton
        AcquisitionModeButton           matlab.ui.control.RadioButton
        SequenceTable                   matlab.ui.control.Table
        CameraControlPanel              matlab.ui.container.Panel
        ZeluxPanel                      matlab.ui.container.Panel
        ZeluxCloseButton                matlab.ui.control.Button
        ZeluxConfigureButton            matlab.ui.control.Button
        ZeluxEstimatedreadouttimeLabel  matlab.ui.control.Label
        ZeluxTriggermodeDropDown        matlab.ui.control.DropDown
        TriggermodeDropDown_3Label      matlab.ui.control.Label
        ZeluxExposuresSpinner           matlab.ui.control.Spinner
        ExposuresSpinner_3Label         matlab.ui.control.Label
        ZeluxStatusLamp                 matlab.ui.control.Lamp
        Andor19331Panel                 matlab.ui.container.Panel
        Andor19331CloseButton           matlab.ui.control.Button
        Andor19331CheckTemperatureButton  matlab.ui.control.Button
        Andor19331ConfigureButton       matlab.ui.control.Button
        Andor19331TemperatureLabel      matlab.ui.control.Label
        Andor19331EstimatedreadouttimeLabel  matlab.ui.control.Label
        Andor19331ReadoutSpeedDropDown  matlab.ui.control.DropDown
        ReadoutspeedLabel_2             matlab.ui.control.Label
        Andor19331TriggermodeDropDown   matlab.ui.control.DropDown
        TriggermodeDropDown_2Label      matlab.ui.control.Label
        Andor19331AcquisitionmodeDropDown  matlab.ui.control.DropDown
        AcquisitionmodeDropDown_2Label  matlab.ui.control.Label
        Andor19331ExposuresSpinner      matlab.ui.control.Spinner
        ExposuresSpinner_2Label         matlab.ui.control.Label
        Andor19331StatusLamp            matlab.ui.control.Lamp
        Andor19330Panel                 matlab.ui.container.Panel
        Andor19330CloseButton           matlab.ui.control.Button
        Andor19330CheckTemperatureButton  matlab.ui.control.Button
        Andor19330ConfigureButton       matlab.ui.control.Button
        Andor19330TemperatureLabel      matlab.ui.control.Label
        Andor19330EstimatedreadouttimeLabel  matlab.ui.control.Label
        Andor19330ReadoutSpeedDropDown  matlab.ui.control.DropDown
        ReadoutspeedLabel               matlab.ui.control.Label
        Andor19330TriggermodeDropDown   matlab.ui.control.DropDown
        TriggermodeDropDownLabel        matlab.ui.control.Label
        Andor19330AcquisitionmodeDropDown  matlab.ui.control.DropDown
        AcquisitionmodeDropDownLabel    matlab.ui.control.Label
        Andor19330ExposuresSpinner      matlab.ui.control.Spinner
        ExposuresSpinnerLabel           matlab.ui.control.Label
        Andor19330StatusLamp            matlab.ui.control.Lamp
        ImageTab                        matlab.ui.container.Tab
        CurrentIndexSpinner             matlab.ui.control.Spinner
        CurrentIndexSpinnerLabel        matlab.ui.control.Label
        Plot3StyleDropDown              matlab.ui.control.DropDown
        Plot3IndexDropDown              matlab.ui.control.DropDown
        Plot2StyleDropDown              matlab.ui.control.DropDown
        Plot2IndexDropDown              matlab.ui.control.DropDown
        Plot1StyleDropDown              matlab.ui.control.DropDown
        Plot1IndexDropDown              matlab.ui.control.DropDown
        SAVEButton                      matlab.ui.control.Button
        STOPButton                      matlab.ui.control.StateButton
        RUNButton                       matlab.ui.control.Button
        RESETButton                     matlab.ui.control.Button
        Axes2StyleDropDown              matlab.ui.control.DropDown
        Axes2IndexDropDown              matlab.ui.control.DropDown
        Axes1StyleDropDown              matlab.ui.control.DropDown
        Axes1IndexDropDown              matlab.ui.control.DropDown
        BigAxes1                        matlab.ui.control.UIAxes
        BigAxes2                        matlab.ui.control.UIAxes
        SmallAxes1                      matlab.ui.control.UIAxes
        SmallAxes2                      matlab.ui.control.UIAxes
        SmallAxes3                      matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        ExportSettingstoFileMenu        matlab.ui.container.Menu
        LoadSettingsfromFileMenu        matlab.ui.container.Menu
        SetCurrentSettingsasDefaultMenu  matlab.ui.container.Menu
    end

    
    properties (SetAccess = private)
        Runner
        AcquisitionConfig
        Cameras
        Data
        Preprocessor
        Analyzer
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startApp(app)
            % Get screen size to set the APP dimension
            offset = [10, 35, -20, -70];
            s = get(0, "MonitorPositions");
            position = s(1, :) + offset;
            for i = 2:size(s, 1)
                if isequal(s(i,3:4), [1920,1080])
                    position = s(i, :) + offset;
                end
            end
            app.UIFigure.Position = position;
            app.SequenceTable.Data = SequenceExample.Sequence4Basic;
            
            % Initialize app properties handles
            app.Runner = Acquisitor();
            app.AcquisitionConfig = app.Runner.Config;
            app.Cameras = app.Runner.Cameras;
            app.Data = app.Runner.Data;
            app.Preprocessor = app.Runner.Preprocessor;
            app.Analyzer = app.Runner.Analyzer;
        end

        % Value changed function: Andor19330AcquisitionmodeDropDown, 
        % ...and 3 other components
        function updateAndor19330Status(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            if isequal(app.Andor19330StatusLamp.Color, [0, 1, 0])
                app.Andor19330StatusLamp.Color = [1, 1, 0];
            end
        end

        % Value changed function: Andor19331AcquisitionmodeDropDown, 
        % ...and 3 other components
        function updateAndor19331Status(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            if isequal(app.Andor19331StatusLamp.Color, [0, 1, 0])
                app.Andor19331StatusLamp.Color = [1, 1, 0];
            end
        end

        % Value changed function: ZeluxExposuresSpinner, 
        % ...and 1 other component
        function updateZeluxStatus(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            if isequal(app.ZeluxStatusLamp.Color, [0, 1, 0])
                app.ZeluxStatusLamp.Color = [1, 1, 0];
            end
        end

        % Button pushed function: Andor19330ConfigureButton
        function configureAndor19330(app, event)
            args = {"Exposure", app.Andor19330ExposuresSpinner.Value, ...
                    "ExternalTrigger", app.Andor19330TriggermodeDropDown.Value == "External"};
            if strcmp(app.Andor19330AcquisitionmodeDropDown.Value, 'Cropped 100x100 1')
                args = [args, {"Cropped", true, "XPixels", 100, "YPixels", 100}];
            else
                args = [args, {"Cropped", false}];
            end
            args = [args, {"FastKinetic", strcmp(app.Andor19330AcquisitionmodeDropDown.Value(1:2), 'FK'), ...
                           "FastKineticSeriesLength", str2double(app.Andor19330AcquisitionmodeDropDown.Value(end))}];
            app.Cameras.Andor19330.init()
            app.Cameras.Andor19330.config(args{:})
            [~, readout_time] = app.Cameras.Andor19330.getTimings();
            app.Andor19330StatusLamp.Color = [0, 1, 0];
            app.Andor19330EstimatedreadouttimeLabel.Text = sprintf("Estimated readout time: %11.4f s", readout_time);
        end

        % Button pushed function: Andor19331ConfigureButton
        function configureAndor19331(app, event)
            args = {"Exposure", app.Andor19331ExposuresSpinner.Value, ...
                    "ExternalTrigger", app.Andor19331TriggermodeDropDown.Value == "External"};
            if strcmp(app.Andor19331AcquisitionmodeDropDown.Value, 'Cropped 100x100 1')
                args = [args, {"Cropped", true, "XPixels", 100, "YPixels", 100}];
            else
                args = [args, {"Cropped", false}];
            end
            args = [args, {"FastKinetic", strcmp(app.Andor19331AcquisitionmodeDropDown.Value(1:2), 'FK'), ...
                           "FastKineticSeriesLength", str2double(app.Andor19331AcquisitionmodeDropDown.Value(end))}];
            app.Cameras.Andor19331.init()
            app.Cameras.Andor19331.config(args{:})
            [~, readout_time] = app.Cameras.Andor19331.getTimings();
            app.Andor19331StatusLamp.Color = [0, 1, 0];
            app.Andor19331EstimatedreadouttimeLabel.Text = sprintf("Estimated readout time: %11.4f s", readout_time);
        end

        % Button pushed function: ZeluxConfigureButton
        function configureZelux(app, event)
            args = {"Exposure", app.ZeluxExposuresSpinner.Value, ...
                    "ExternalTrigger", app.ZeluxTriggermodeDropDown.Value == "External"};
            app.Cameras.Zelux.init()
            app.Cameras.Zelux.config(args{:})
            [~, readout_time] = app.Cameras.Zelux.getTimings();
            app.ZeluxStatusLamp.Color = [0, 1, 0];
            app.ZeluxEstimatedreadouttimeLabel.Text = sprintf("Estimated readout time: %11.4f s", readout_time);
        end

        % Button pushed function: Andor19330CheckTemperatureButton
        function checkTemperatureAndor19330(app, event)
            [~, temp, status] = app.Cameras.Andor19330.checkTemperature();
            app.Andor19330TemperatureLabel.Text = sprintf("Current Temperature: %.3g\n%s", temp, status);
        end

        % Button pushed function: Andor19331CheckTemperatureButton
        function checkTemperatureAndor19331(app, event)
            [~, temp, status] = app.Cameras.Andor19331.checkTemperature();
            app.Andor19331TemperatureLabel.Text = sprintf("Current Temperature: %.3g\n%s", temp, status);
        end

        % Button pushed function: Andor19330CloseButton
        function closeAndor19330(app, event)
            app.Cameras.Andor19330.close()
            app.Andor19330StatusLamp.Color = [1, 0, 0];
        end

        % Button pushed function: Andor19331CloseButton
        function closeAndor19331(app, event)
            app.Cameras.Andor19331.close()
            app.Andor19331StatusLamp.Color = [1, 0, 0];
        end

        % Button pushed function: ZeluxCloseButton
        function closeZelux(app, event)
            app.Cameras.Zelux.close()
            app.ZeluxStatusLamp.Color = [1, 0, 0];
        end

        % Callback function: AcquisitiontimeoutsSpinner, 
        % ...and 3 other components
        function updateAcquisitionConfig(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.SmallAxes3 = uiaxes(app.ImageTab);
            xlabel(app.SmallAxes3, 'X')
            ylabel(app.SmallAxes3, 'Y')
            zlabel(app.SmallAxes3, 'Z')
            app.SmallAxes3.Tag = 'SmallAxes3';
            app.SmallAxes2 = uiaxes(app.ImageTab);
            xlabel(app.SmallAxes2, 'X')
            ylabel(app.SmallAxes2, 'Y')
            zlabel(app.SmallAxes2, 'Z')
            app.SmallAxes1 = uiaxes(app.ImageTab);
            xlabel(app.SmallAxes1, 'X')
            ylabel(app.SmallAxes1, 'Y')
            zlabel(app.SmallAxes1, 'Z')
            app.BigAxes2 = uiaxes(app.ImageTab);
            title(app.BigAxes2, ' ')
            app.BigAxes1 = uiaxes(app.ImageTab);
            app.Axes1IndexDropDown = uidropdown(app.ImageTab);
            app.Axes1IndexDropDown.Items = {'Image'};
            app.Axes1IndexDropDown.Value = 'Image';
            app.Axes1StyleDropDown = uidropdown(app.ImageTab);
            app.Axes1StyleDropDown.Items = {'Raw', 'Cleaned', 'Background offset'};
            app.Axes1StyleDropDown.Value = 'Raw';
            app.Axes2IndexDropDown = uidropdown(app.ImageTab);
            app.Axes2IndexDropDown.Items = {'Image'};
            app.Axes2IndexDropDown.Value = 'Image';
            app.Axes2StyleDropDown = uidropdown(app.ImageTab);
            app.Axes2StyleDropDown.Items = {'Raw', 'Cleaned', 'Background offset'};
            app.Axes2StyleDropDown.Value = 'Raw';
            app.RESETButton = uibutton(app.ImageTab, 'push');
            app.RESETButton.Text = 'RESET';
            app.RUNButton = uibutton(app.ImageTab, 'push');
            app.RUNButton.Text = 'RUN';
            app.STOPButton = uibutton(app.ImageTab, 'state');
            app.STOPButton.Text = 'STOP';
            app.SAVEButton = uibutton(app.ImageTab, 'push');
            app.SAVEButton.Text = 'SAVE';
            app.Plot1IndexDropDown = uidropdown(app.ImageTab);
            app.Plot1IndexDropDown.Items = {'Image'};
            app.Plot1IndexDropDown.Value = 'Image';
            app.Plot1StyleDropDown = uidropdown(app.ImageTab);
            app.Plot1StyleDropDown.Items = {};
            app.Plot1StyleDropDown.Value = {};
            app.Plot2IndexDropDown = uidropdown(app.ImageTab);
            app.Plot2IndexDropDown.Items = {'Image'};
            app.Plot2IndexDropDown.Value = 'Image';
            app.Plot2StyleDropDown = uidropdown(app.ImageTab);
            app.Plot2StyleDropDown.Items = {};
            app.Plot2StyleDropDown.Value = {};
            app.Plot3IndexDropDown = uidropdown(app.ImageTab);
            app.Plot3IndexDropDown.Items = {'Image'};
            app.Plot3IndexDropDown.Value = 'Image';
            app.Plot3StyleDropDown = uidropdown(app.ImageTab);
            app.Plot3StyleDropDown.Items = {};
            app.Plot3StyleDropDown.Value = {};
            app.CurrentIndexSpinnerLabel = uilabel(app.ImageTab);
            app.CurrentIndexSpinnerLabel.Text = 'Current Index';
            app.CurrentIndexSpinner = uispinner(app.ImageTab);
            app.CurrentIndexSpinner.Limits = [0 Inf];
            app.CurrentIndexSpinner.RoundFractionalValues = 'on';
            app.CurrentIndexSpinner.ValueDisplayFormat = '%d';
            app.CurrentIndexSpinner.Enable = 'off';
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.CameraControlPanel = uipanel(app.SettingTab);
            app.CameraControlPanel.Title = 'Camera Control';
            app.Andor19330Panel = uipanel(app.CameraControlPanel);
            app.Andor19330Panel.Title = 'Andor 19330';
            app.Andor19330StatusLamp = uilamp(app.Andor19330Panel);
            app.ExposuresSpinnerLabel = uilabel(app.Andor19330Panel);
            app.ExposuresSpinnerLabel.Text = 'Exposure (s)';
            app.Andor19330ExposuresSpinner = uispinner(app.Andor19330Panel);
            app.Andor19330ExposuresSpinner.Step = 0.1;
            app.Andor19330ExposuresSpinner.Limits = [0 Inf];
            app.Andor19330ExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330ExposuresSpinner.Value = 0.2;
            app.AcquisitionmodeDropDownLabel = uilabel(app.Andor19330Panel);
            app.AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.Andor19330AcquisitionmodeDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330AcquisitionmodeDropDown.Items = {'Full Frame 1', 'Cropped 100x100 1', 'FK 2', 'FK 4', 'FK 8'};
            app.Andor19330AcquisitionmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330AcquisitionmodeDropDown.Value = 'Full Frame 1';
            app.TriggermodeDropDownLabel = uilabel(app.Andor19330Panel);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.Andor19330TriggermodeDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330TriggermodeDropDown.Items = {'External', 'Internal'};
            app.Andor19330TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330TriggermodeDropDown.Value = 'External';
            app.ReadoutspeedLabel = uilabel(app.Andor19330Panel);
            app.ReadoutspeedLabel.Text = 'Readout speed';
            app.Andor19330ReadoutSpeedDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330ReadoutSpeedDropDown.Items = {'1MHz', '3MHz', '5MHz'};
            app.Andor19330ReadoutSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330ReadoutSpeedDropDown.Value = '1MHz';
            app.Andor19330EstimatedreadouttimeLabel = uilabel(app.Andor19330Panel);
            app.Andor19330EstimatedreadouttimeLabel.Text = 'Estimated readout time: ';
            app.Andor19330TemperatureLabel = uilabel(app.Andor19330Panel);
            app.Andor19330TemperatureLabel.Text = 'Current Temperature:';
            app.Andor19330ConfigureButton = uibutton(app.Andor19330Panel, 'push');
            app.Andor19330ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @configureAndor19330, true);
            app.Andor19330ConfigureButton.Text = 'Configure Andor 19330';
            app.Andor19330CheckTemperatureButton = uibutton(app.Andor19330Panel, 'push');
            app.Andor19330CheckTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @checkTemperatureAndor19330, true);
            app.Andor19330CheckTemperatureButton.Text = 'Check Temperature';
            app.Andor19330CloseButton = uibutton(app.Andor19330Panel, 'push');
            app.Andor19330CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeAndor19330, true);
            app.Andor19330CloseButton.Text = 'Close Andor 19330';
            app.Andor19331Panel = uipanel(app.CameraControlPanel);
            app.Andor19331Panel.Title = 'Andor 19331';
            app.Andor19331StatusLamp = uilamp(app.Andor19331Panel);
            app.ExposuresSpinner_2Label = uilabel(app.Andor19331Panel);
            app.ExposuresSpinner_2Label.Text = 'Exposure (s)';
            app.Andor19331ExposuresSpinner = uispinner(app.Andor19331Panel);
            app.Andor19331ExposuresSpinner.Step = 0.1;
            app.Andor19331ExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331ExposuresSpinner.Value = 0.2;
            app.AcquisitionmodeDropDown_2Label = uilabel(app.Andor19331Panel);
            app.AcquisitionmodeDropDown_2Label.Text = 'Acquisition mode';
            app.Andor19331AcquisitionmodeDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331AcquisitionmodeDropDown.Items = {'Full Frame 1', 'Cropped 100x100 1', 'FK 2', 'FK 4', 'FK 8'};
            app.Andor19331AcquisitionmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331AcquisitionmodeDropDown.Value = 'Full Frame 1';
            app.TriggermodeDropDown_2Label = uilabel(app.Andor19331Panel);
            app.TriggermodeDropDown_2Label.Text = 'Trigger mode';
            app.Andor19331TriggermodeDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331TriggermodeDropDown.Items = {'External', 'Internal'};
            app.Andor19331TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331TriggermodeDropDown.Value = 'External';
            app.ReadoutspeedLabel_2 = uilabel(app.Andor19331Panel);
            app.ReadoutspeedLabel_2.Text = 'Readout speed';
            app.Andor19331ReadoutSpeedDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331ReadoutSpeedDropDown.Items = {'1MHz', '3MHz', '5MHz'};
            app.Andor19331ReadoutSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331ReadoutSpeedDropDown.Value = '1MHz';
            app.Andor19331EstimatedreadouttimeLabel = uilabel(app.Andor19331Panel);
            app.Andor19331EstimatedreadouttimeLabel.Text = 'Estimated readout time: ';
            app.Andor19331TemperatureLabel = uilabel(app.Andor19331Panel);
            app.Andor19331TemperatureLabel.Text = 'Current Temperature: ';
            app.Andor19331ConfigureButton = uibutton(app.Andor19331Panel, 'push');
            app.Andor19331ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @configureAndor19331, true);
            app.Andor19331ConfigureButton.Text = 'Configure Andor 19331';
            app.Andor19331CheckTemperatureButton = uibutton(app.Andor19331Panel, 'push');
            app.Andor19331CheckTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @checkTemperatureAndor19331, true);
            app.Andor19331CheckTemperatureButton.Text = 'Check Temperature';
            app.Andor19331CloseButton = uibutton(app.Andor19331Panel, 'push');
            app.Andor19331CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeAndor19331, true);
            app.Andor19331CloseButton.Text = 'Close Andor 19331';
            app.ZeluxPanel = uipanel(app.CameraControlPanel);
            app.ZeluxPanel.Title = 'Zelux';
            app.ZeluxStatusLamp = uilamp(app.ZeluxPanel);
            app.ExposuresSpinner_3Label = uilabel(app.ZeluxPanel);
            app.ExposuresSpinner_3Label.Text = 'Exposure (s)';
            app.ZeluxExposuresSpinner = uispinner(app.ZeluxPanel);
            app.ZeluxExposuresSpinner.Step = 0.0001;
            app.ZeluxExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateZeluxStatus, true);
            app.ZeluxExposuresSpinner.Value = 0.0001;
            app.TriggermodeDropDown_3Label = uilabel(app.ZeluxPanel);
            app.TriggermodeDropDown_3Label.Text = 'Trigger mode';
            app.ZeluxTriggermodeDropDown = uidropdown(app.ZeluxPanel);
            app.ZeluxTriggermodeDropDown.Items = {'External', 'Internal'};
            app.ZeluxTriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateZeluxStatus, true);
            app.ZeluxTriggermodeDropDown.Value = 'External';
            app.ZeluxEstimatedreadouttimeLabel = uilabel(app.ZeluxPanel);
            app.ZeluxEstimatedreadouttimeLabel.Text = 'Estimated readout time: ';
            app.ZeluxConfigureButton = uibutton(app.ZeluxPanel, 'push');
            app.ZeluxConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @configureZelux, true);
            app.ZeluxConfigureButton.Text = 'Configure Zelux';
            app.ZeluxCloseButton = uibutton(app.ZeluxPanel, 'push');
            app.ZeluxCloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeZelux, true);
            app.ZeluxCloseButton.Text = 'Close Zelux';
            app.OperationPanel = uipanel(app.SettingTab);
            app.OperationPanel.Title = 'Operation';
            app.SequenceTable = uitable(app.OperationPanel);
            app.SequenceTable.ColumnName = {'Order'; 'Camera'; 'Label'; 'Type'; 'Note'};
            app.SequenceTable.RowName = {};
            app.SequenceTable.ColumnEditable = [false true true true true];
            app.SequenceTable.DisplayDataChangedFcn = createCallbackFcn(app, @updateAcquisitionConfig, true);
            app.SequenceTable.Multiselect = 'off';
            app.OperationModeButtonGroup = uibuttongroup(app.OperationPanel);
            app.OperationModeButtonGroup.Title = 'Operation Mode';
            app.AcquisitionModeButton = uiradiobutton(app.OperationModeButtonGroup);
            app.AcquisitionModeButton.Text = 'Acquisition';
            app.AcquisitionModeButton.Value = true;
            app.ReplayModeButton = uiradiobutton(app.OperationModeButtonGroup);
            app.ReplayModeButton.Text = 'Replay';
            app.AcquisitionSettingPanel = uipanel(app.OperationPanel);
            app.AcquisitionSettingPanel.Title = 'Acquisition Setting';
            app.NumberofacquisitionsSpinnerLabel = uilabel(app.AcquisitionSettingPanel);
            app.NumberofacquisitionsSpinnerLabel.Text = 'Number of acquisitions';
            app.NumberofacquisitionsSpinner = uispinner(app.AcquisitionSettingPanel);
            app.NumberofacquisitionsSpinner.ValueDisplayFormat = '%d';
            app.NumberofacquisitionsSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAcquisitionConfig, true);
            app.NumberofacquisitionsSpinner.Value = 20;
            app.AcquisitiontimeoutsSpinnerLabel = uilabel(app.AcquisitionSettingPanel);
            app.AcquisitiontimeoutsSpinnerLabel.Text = 'Acquisition time out (s)';
            app.AcquisitiontimeoutsSpinner = uispinner(app.AcquisitionSettingPanel);
            app.AcquisitiontimeoutsSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAcquisitionConfig, true);
            app.AcquisitiontimeoutsSpinner.Value = 1000;
            app.PoolingintervalsLabel = uilabel(app.AcquisitionSettingPanel);
            app.PoolingintervalsLabel.Text = 'Refresh interval (s)';
            app.RefreshIntervalSpinner = uispinner(app.AcquisitionSettingPanel);
            app.RefreshIntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAcquisitionConfig, true);
            app.RefreshIntervalSpinner.Value = 0.01;
            app.EstimatedDatasetsizeLabel = uilabel(app.AcquisitionSettingPanel);
            app.EstimatedDatasetsizeLabel.Text = 'Estimated Dataset size: ';
            app.InitializeDatasetStorageButton = uibutton(app.AcquisitionSettingPanel, 'push');
            app.InitializeDatasetStorageButton.Text = 'Initialize Dataset Storage';
            app.ReplaySettingPanel = uipanel(app.OperationPanel);
            app.ReplaySettingPanel.Enable = 'off';
            app.ReplaySettingPanel.Title = 'Replay Setting';
            app.DatasetLabel = uilabel(app.ReplaySettingPanel);
            app.DatasetLabel.Text = {'Dataset is loaded from: '; ''};
            app.LoadDatasetButton = uibutton(app.ReplaySettingPanel, 'push');
            app.LoadDatasetButton.Text = 'Load Dataset';
            app.TotalRunTimeLabel = uilabel(app.UIFigure);
            app.TotalRunTimeLabel.Text = 'Total Run Time:';
            app.ElapsedTimeLabel = uilabel(app.UIFigure);
            app.ElapsedTimeLabel.Text = 'Elapsed Time:';
            app.RunNumLabel = uilabel(app.UIFigure);
            app.RunNumLabel.Text = 'Run Num: ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportSettingstoFileMenu = uimenu(app.ContextMenu);
            app.ExportSettingstoFileMenu.Text = 'Export Settings to File';
            app.LoadSettingsfromFileMenu = uimenu(app.ContextMenu);
            app.LoadSettingsfromFileMenu.Text = 'Load Settings from File';
            app.SetCurrentSettingsasDefaultMenu = uimenu(app.ContextMenu);
            app.SetCurrentSettingsasDefaultMenu.Text = 'Set Current Settings as Default';
            % Assign app.ContextMenu
            app.SettingTab.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcquisitorApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startApp)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end