classdef ImageAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImageTab                        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Plot2DropDown                   matlab.ui.control.DropDown
        Plot2DropDownLabel              matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.StateButton
        PauseContinueButton             matlab.ui.control.StateButton
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        Axes2DropDown                   matlab.ui.control.DropDown
        Axes2DropDownLabel              matlab.ui.control.Label
        Plot1DropDown                   matlab.ui.control.DropDown
        Plot1DropDownLabel              matlab.ui.control.Label
        Axes1DropDown                   matlab.ui.control.DropDown
        Axes1DropDownLabel              matlab.ui.control.Label
        SmallAxes1_2                    matlab.ui.control.UIAxes
        SmallAxes1                      matlab.ui.control.UIAxes
        BigAxes2                        matlab.ui.control.UIAxes
        BigAxes1                        matlab.ui.control.UIAxes
        SettingsTab                     matlab.ui.container.Tab
        AcquisitionSettingsPanel        matlab.ui.container.Panel
        CamerasListBox                  matlab.ui.control.ListBox
        CamerasListBoxLabel             matlab.ui.control.Label
        AcquisitiontimeoutsSpinner      matlab.ui.control.Spinner
        AcquisitiontimeoutsSpinnerLabel  matlab.ui.control.Label
        DatasetsizeSpinner              matlab.ui.control.Spinner
        DatasetsizeSpinnerLabel         matlab.ui.control.Label
        BackgroundshotCheckBox          matlab.ui.control.CheckBox
        ZeluxSettingsPanel              matlab.ui.container.Panel
        ZeluxStatusLamp                 matlab.ui.control.Lamp
        CloseZeluxButton                matlab.ui.control.Button
        ConfigureZeluxButton            matlab.ui.control.Button
        ZeluxTriggermodeDropDown        matlab.ui.control.DropDown
        TriggermodeDropDown_3Label      matlab.ui.control.Label
        ZeluxExposuresSpinner           matlab.ui.control.Spinner
        ExposuresSpinner_3Label         matlab.ui.control.Label
        Andor19331SettingsPanel         matlab.ui.container.Panel
        Andor19331StatusLamp            matlab.ui.control.Lamp
        CloseAndor19331Button           matlab.ui.control.Button
        ConfigureAndor19331Button       matlab.ui.control.Button
        Andor19331TriggermodeDropDown   matlab.ui.control.DropDown
        TriggermodeDropDown_2Label      matlab.ui.control.Label
        Andor19331AcquisitionmodeDropDown  matlab.ui.control.DropDown
        AcquisitionmodeDropDown_2Label  matlab.ui.control.Label
        Andor19331ExposuresSpinner      matlab.ui.control.Spinner
        ExposuresSpinner_2Label         matlab.ui.control.Label
        Andor19330SettingsPanel         matlab.ui.container.Panel
        Andor19330StatusLamp            matlab.ui.control.Lamp
        CloseAndor19330Button           matlab.ui.control.Button
        ConfigureAndor19330Button       matlab.ui.control.Button
        Andor19330TriggermodeDropDown   matlab.ui.control.DropDown
        TriggermodeDropDownLabel        matlab.ui.control.Label
        Andor19330AcquisitionmodeDropDown  matlab.ui.control.DropDown
        AcquisitionmodeDropDownLabel    matlab.ui.control.Label
        Andor19330ExposuresSpinner      matlab.ui.control.Spinner
        ExposuresSpinnerLabel           matlab.ui.control.Label
        AxesMenu                        matlab.ui.container.ContextMenu
        ToggleAutoscaleMenu             matlab.ui.container.Menu
        SettingMenu                     matlab.ui.container.ContextMenu
        SetcurrentsettingsasdefaultMenu  matlab.ui.container.Menu
        ExportcurrentsettingsasMATMenu  matlab.ui.container.Menu
        LoadsettingsfromfileMenu        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Live = struct()
        Data = {}
        Layout = struct()
        ZeluxHandle = {}
    end
    
    methods (Access = private)

        function initializeAcquisition(app)
            % Check if there is stored data
            if isfield(app.Data, 'Image')
                answer = questdlg('Start a new acquisition will erase the data in memory. Would you like to save it before proceeding?', ...
                    'Image Aquisition App', ...
                    'Yes', 'No', 'Yes');
                switch answer
                    case 'Yes'
                        saveData(app.Data, app.Live)
                    case 'No'
                end
            end
            
            % Initialize camera and Data Config
            camera = app.SelectcameraButtonGroup.SelectedObject.Text;
            external_trigger = strcmp(app.Andor19330TriggermodeDropDown.Value, 'External');
            switch camera
                case {'Andor 19330', 'Andor 19331'}
                    serial = str2double(camera(7:end));
                    initializeAndor()
                    setCurrentAndor(serial)
                    switch app.Andor19330AcquisitionmodeDropDown.Value
                        case 'Full frame'
                            num_frames = 1;
                        case 'FK 2'
                            num_frames = 2;
                        case 'FK 4'
                            num_frames = 4;
                        case 'FK 8'
                            num_frames = 8;
                    end
                    setModeFK("num_frames",num_frames, ...
                        "exposure",app.Andor19330ExposuresSpinner.Value, ...
                        "external_trigger",external_trigger)
                    app.Data.Config = getAcquisitionConfig("num_frames",num_frames);                
                case 'Zelux'
                    app.Data.Config = struct(...
                        'Exposure', app.Andor19330ExposuresSpinner.Value, ...
                        'XPixels', 1440, ...
                        'YPixels', 1080);
                    if ~isempty(app.ZeluxHandle)
                        closeZeluxCallback(app.ZeluxHandle{:})
                    end
                    [tlCameraSDK, tlCamera] = initializeZelux('exposure',app.Andor19330ExposuresSpinner.Value, ...
                        'external_trigger',external_trigger);
                    app.ZeluxHandle = {tlCameraSDK, tlCamera};
            end
            app.Data.Config.MaxImage = app.DatasetsizeSpinner.Value;
            app.Data.Config.External = external_trigger;
            app.Data.Config.Serial = camera;
            app.Data.Config.Background = app.BackgroundshotCheckBox.Value;
            fprintf('Camera Config for acquisition:\n')
            disp(app.Data.Config)
            
            % Initialize Data
            app.Data.Image = zeros(app.Data.Config.XPixels, app.Data.Config.YPixels, app.Data.Config.MaxImage);
            if app.Data.Config.Background
                app.Data.Background = zeros(app.Data.Config.XPixels, app.Data.Config.YPixels, app.Data.Config.MaxImage);
            end
            
            % Initialize statistics for live updates
            app.Live.Current = 0;
            app.Live.MeanCount = nan(1, app.Data.Config.MaxImage);
        
        end

        function image = acquireImage(app)
            if strcmp(app.Data.Config.Serial, 'Zelux')
                image = acquireZeluxImage(app.ZeluxHandle{2}, "timeout",app.AcquisitiontimeoutsSpinner.Value);
            else
                image = acquireAndorImage("timeout",app.AcquisitiontimeoutsSpinner.Value);
            end
        end
        
        function updateLayout(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startApp(app)
            % Get screen size to set the APP dimension
            s = get(0, 'ScreenSize');
            app.UIFigure.Position = [s(1)+50, s(2)+50, s(3)-100, s(4)-100];
        end

        % Close request function: UIFigure
        function closeUI(app, event)
            if ~isempty(app.ZeluxHandle)
                closeZeluxCallback(app.ZeluxHandle{:})
            end            
            % TODO: load the last state and resume from the latest settings
            delete(app)
        end

        % Button pushed function: RunButton
        function run(app, event)
            
            % Initialize the acquisition
            initializeAcquisition(app)
            app.StatusLamp.Color = [1, 1, 0];

            % Run continuously until aborted
            while ~app.StopButton.Value
                while ~app.PauseContinueButton.Value && ~app.StopButton.Value
                    tic
                    
                    app.StatusLamp.Color = [1, 1, 0];
                    
                    % Acquire data
                    image = acquireImage(app);
                    if app.BackgroundshotCheckBox.Value
                        background = acquireImage(app);
                    end
                    
                    app.StatusLamp.Color = [0, 1, 0];

                    % Update data storage
                    app.Live.Current = app.Live.Current + 1;
                    if app.Live.Current <= app.Data.Config.MaxImage
                        app.Data.Image(:,:,app.Live.Current) = image;
                        if app.Data.Config.Background
                            app.Data.Background(:,:,app.Live.Current) = background;
                        end
                    else
                        % If the current shot is above the max, shift the data storage
                        app.Data.Image = circshift(app.Data.Image, -1, 3);
                        app.Data.Image(:, :, end) = image;
                        if app.Data.Config.Background
                            app.Data.Background = circshift(app.Data.Background, -1, 3);
                            app.Data.Background(:, :, end) = background;
                        end                        
                    end

                    % Update layout
                    signal = image - background;

                    imagesc(app.BigAxes1, image)
                    app.BigAxes1.DataAspectRatio = [1, 1, 1];
                    app.BigAxes1.Title.String = sprintf('Current image: %d/%d', app.Live.Current, app.Data.Config.MaxImage);
                    colorbar(app.BigAxes1)

                    imagesc(app.BigAxes2, signal)
                    app.BigAxes2.DataAspectRatio = [1, 1, 1];
                    app.BigAxes2.Title.String = 'Current signal';
                    colorbar(app.BigAxes2)
                    clim(app.BigAxes2, [-10 100])
                    drawnow
                    
                    pause(0.1)
                    
                    toc
                end

                pause(0.1)
            end

            fprintf('Acquisition stopped\n')
            app.StatusLamp.Color = [1, 0, 0];
            
            if ~isempty(app.ZeluxHandle)
                closeZelux(app.ZeluxHandle{:})
                app.ZeluxHandle = {};
            end
            app.StopButton.Value = false;
            
        end

        % Button pushed function: SaveButton
        function save(app, event)
            saveData(app.Data, app.Live)

        end

        % Button pushed function: ConfigureAndor19330Button
        function configure19330(app, event)
            initializeAndor(19330, "close_unselected",false)
            setCurrentAndor(19330)

            exposure = app.Andor19330ExposuresSpinner.Value;
            external_trigger = strcmp(app.Andor19330TriggermodeDropDown.Value, 'External');
            switch app.Andor19330AcquisitionmodeDropDown.Value
                case 'Full frame'
                    num_frames = 1;
                case 'FK 2'
                    num_frames = 2;
                case 'FK 4'
                    num_frames = 4;
                case 'FK 8'
                    num_frames = 8;
            end
            setModeFK("num_frames",num_frames, ...
                "exposure",exposure, ...
                "external_trigger",external_trigger)

            % After configuration, set the indicator to Green (RGB)
            app.Andor19330StatusLamp.Color = [0, 1, 0];

        end

        % Button pushed function: ConfigureAndor19331Button
        function configure19331(app, event)
            initializeAndor(19331, "close_unselected",false)
            setCurrentAndor(19331)
            
            exposure = app.Andor19331ExposuresSpinner.Value;
            external_trigger = strcmp(app.Andor19331TriggermodeDropDown.Value, 'External');
            switch app.Andor19331AcquisitionmodeDropDown.Value
                case 'Full frame'
                    num_frames = 1;
                case 'FK 2'
                    num_frames = 2;
                case 'FK 4'
                    num_frames = 4;
                case 'FK 8'
                    num_frames = 8;
            end
            setModeFK("num_frames",num_frames, ...
            "exposure",exposure, ...
            "external_trigger",external_trigger)

            % After configuration, set the indicator to Green (RGB)
            app.Andor19331StatusLamp.Color = [0, 1, 0];

        end

        % Button pushed function: ConfigureZeluxButton
        function configureZelux(app, event)
            exposure = app.ZeluxExposuresSpinner.Value;
            external_trigger = strcmp(app.ZeluxTriggermodeDropDown.Value, 'External');
            [tlCameraSDK, tlCamera] = initializeZelux("exposure",exposure, "external_trigger",external_trigger);
            
            app.ZeluxHandle = {tlCameraSDK, tlCamera};

            % After configuration, set the indicator to Green (RGB)
            app.ZeluxStatusLamp.Color = [0, 1, 0];

        end

        % Button pushed function: CloseAndor19330Button
        function close19330(app, event)
            closeAndor(19330)

            % After closing, set the indicator to Red (RGB)
            app.Andor19330StatusLamp.Color = [1, 0, 0];

        end

        % Button pushed function: CloseAndor19331Button
        function close19331(app, event)
            closeAndor(19331)

            % After closing, set the indicator to Red (RGB)
            app.Andor19331StatusLamp.Color = [1, 0, 0];

        end

        % Button pushed function: CloseZeluxButton
        function closeZeluxCallback(app, event)
            if ~isempty(app.ZeluxHandle)
                closeZelux(app.ZeluxHandle{:})
            end

            % After closing, set the indicator to Red (RGB)
            app.ZeluxStatusLamp.Color = [1, 0, 0];

        end

        % Value changed function: Andor19330AcquisitionmodeDropDown, 
        % ...and 2 other components
        function updateAndor19330Status(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            app.Andor19330StatusLamp.Color = [1, 1, 0];
        end

        % Value changed function: Andor19331AcquisitionmodeDropDown, 
        % ...and 2 other components
        function updateAndor19331Status(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            app.Andor19331StatusLamp.Color = [1, 1, 0];
        end

        % Value changed function: ZeluxExposuresSpinner, 
        % ...and 1 other component
        function updateZeluxStatus(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            app.ZeluxStatusLamp.Color = [1, 1, 0];
        end

        % Value changed function: Axes1DropDown
        function updateAxes1Content(app, event)
            value = app.Axes1DropDown.Value;
            
        end

        % Value changed function: Axes2DropDown
        function updateAxes2Content(app, event)
            value = app.Axes2DropDown.Value;
            
        end

        % Value changed function: Plot1DropDown
        function updatePlot1Content(app, event)
            value = app.Plot1DropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeUI, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.GridLayout = uigridlayout(app.ImageTab);
            app.BigAxes1 = uiaxes(app.GridLayout);
            app.BigAxes2 = uiaxes(app.GridLayout);
            title(app.BigAxes2, ' ')
            app.SmallAxes1 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes1, 'X')
            ylabel(app.SmallAxes1, 'Y')
            zlabel(app.SmallAxes1, 'Z')
            app.SmallAxes1_2 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes1_2, 'X')
            ylabel(app.SmallAxes1_2, 'Y')
            zlabel(app.SmallAxes1_2, 'Z')
            app.Axes1DropDownLabel = uilabel(app.GridLayout);
            app.Axes1DropDownLabel.Text = 'Axes 1';
            app.Axes1DropDown = uidropdown(app.GridLayout);
            app.Axes1DropDown.Items = {'Image1', 'Image2', 'Signal'};
            app.Axes1DropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxes1Content, true);
            app.Axes1DropDown.Value = 'Image1';
            app.Plot1DropDownLabel = uilabel(app.GridLayout);
            app.Plot1DropDownLabel.Text = 'Plot 1';
            app.Plot1DropDown = uidropdown(app.GridLayout);
            app.Plot1DropDown.Items = {'MeanCount', 'MaxCount'};
            app.Plot1DropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlot1Content, true);
            app.Plot1DropDown.Value = 'MeanCount';
            app.Axes2DropDownLabel = uilabel(app.GridLayout);
            app.Axes2DropDownLabel.Text = 'Axes 2';
            app.Axes2DropDown = uidropdown(app.GridLayout);
            app.Axes2DropDown.Items = {'Image1', 'Image2', 'Signal'};
            app.Axes2DropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxes2Content, true);
            app.Axes2DropDown.Value = 'Image1';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.RunButton.Text = 'Run';
            app.StatusLampLabel = uilabel(app.GridLayout);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.GridLayout);
            app.PauseContinueButton = uibutton(app.GridLayout, 'state');
            app.PauseContinueButton.Text = 'Pause / Continue';
            app.StopButton = uibutton(app.GridLayout, 'state');
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveButton.Text = 'Save';
            app.Plot2DropDownLabel = uilabel(app.GridLayout);
            app.Plot2DropDownLabel.Text = 'Plot 2';
            app.Plot2DropDown = uidropdown(app.GridLayout);
            app.Plot2DropDown.Items = {'MeanCount', 'MaxCount'};
            app.Plot2DropDown.Value = 'MeanCount';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.Andor19330SettingsPanel = uipanel(app.SettingsTab);
            app.Andor19330SettingsPanel.Title = 'Andor 19330 Settings';
            app.ExposuresSpinnerLabel = uilabel(app.Andor19330SettingsPanel);
            app.ExposuresSpinnerLabel.Text = 'Exposure (s)';
            app.Andor19330ExposuresSpinner = uispinner(app.Andor19330SettingsPanel);
            app.Andor19330ExposuresSpinner.Step = 0.1;
            app.Andor19330ExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330ExposuresSpinner.Value = 0.2;
            app.AcquisitionmodeDropDownLabel = uilabel(app.Andor19330SettingsPanel);
            app.AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.Andor19330AcquisitionmodeDropDown = uidropdown(app.Andor19330SettingsPanel);
            app.Andor19330AcquisitionmodeDropDown.Items = {'Full frame', 'FK 2', 'FK 4', 'FK 8'};
            app.Andor19330AcquisitionmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330AcquisitionmodeDropDown.Value = 'Full frame';
            app.TriggermodeDropDownLabel = uilabel(app.Andor19330SettingsPanel);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.Andor19330TriggermodeDropDown = uidropdown(app.Andor19330SettingsPanel);
            app.Andor19330TriggermodeDropDown.Items = {'External', 'Internal'};
            app.Andor19330TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330TriggermodeDropDown.Value = 'External';
            app.ConfigureAndor19330Button = uibutton(app.Andor19330SettingsPanel, 'push');
            app.ConfigureAndor19330Button.ButtonPushedFcn = createCallbackFcn(app, @configure19330, true);
            app.ConfigureAndor19330Button.Text = 'Configure Andor 19330';
            app.CloseAndor19330Button = uibutton(app.Andor19330SettingsPanel, 'push');
            app.CloseAndor19330Button.ButtonPushedFcn = createCallbackFcn(app, @close19330, true);
            app.CloseAndor19330Button.Text = 'Close Andor 19330';
            app.Andor19330StatusLamp = uilamp(app.Andor19330SettingsPanel);
            app.Andor19331SettingsPanel = uipanel(app.SettingsTab);
            app.Andor19331SettingsPanel.Title = 'Andor 19331 Settings';
            app.ExposuresSpinner_2Label = uilabel(app.Andor19331SettingsPanel);
            app.ExposuresSpinner_2Label.Text = 'Exposure (s)';
            app.Andor19331ExposuresSpinner = uispinner(app.Andor19331SettingsPanel);
            app.Andor19331ExposuresSpinner.Step = 0.1;
            app.Andor19331ExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331ExposuresSpinner.Value = 0.2;
            app.AcquisitionmodeDropDown_2Label = uilabel(app.Andor19331SettingsPanel);
            app.AcquisitionmodeDropDown_2Label.Text = 'Acquisition mode';
            app.Andor19331AcquisitionmodeDropDown = uidropdown(app.Andor19331SettingsPanel);
            app.Andor19331AcquisitionmodeDropDown.Items = {'Full frame', 'FK 2', 'FK 4', 'FK 8'};
            app.Andor19331AcquisitionmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331AcquisitionmodeDropDown.Value = 'Full frame';
            app.TriggermodeDropDown_2Label = uilabel(app.Andor19331SettingsPanel);
            app.TriggermodeDropDown_2Label.Text = 'Trigger mode';
            app.Andor19331TriggermodeDropDown = uidropdown(app.Andor19331SettingsPanel);
            app.Andor19331TriggermodeDropDown.Items = {'External', 'Internal'};
            app.Andor19331TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331TriggermodeDropDown.Value = 'External';
            app.ConfigureAndor19331Button = uibutton(app.Andor19331SettingsPanel, 'push');
            app.ConfigureAndor19331Button.ButtonPushedFcn = createCallbackFcn(app, @configure19331, true);
            app.ConfigureAndor19331Button.Text = 'Configure Andor 19331';
            app.CloseAndor19331Button = uibutton(app.Andor19331SettingsPanel, 'push');
            app.CloseAndor19331Button.ButtonPushedFcn = createCallbackFcn(app, @close19331, true);
            app.CloseAndor19331Button.Text = 'Close Andor 19331';
            app.Andor19331StatusLamp = uilamp(app.Andor19331SettingsPanel);
            app.ZeluxSettingsPanel = uipanel(app.SettingsTab);
            app.ZeluxSettingsPanel.Title = 'Zelux Settings';
            app.ExposuresSpinner_3Label = uilabel(app.ZeluxSettingsPanel);
            app.ExposuresSpinner_3Label.Text = 'Exposure (s)';
            app.ZeluxExposuresSpinner = uispinner(app.ZeluxSettingsPanel);
            app.ZeluxExposuresSpinner.Step = 0.1;
            app.ZeluxExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateZeluxStatus, true);
            app.ZeluxExposuresSpinner.Value = 1e-06;
            app.TriggermodeDropDown_3Label = uilabel(app.ZeluxSettingsPanel);
            app.TriggermodeDropDown_3Label.Text = 'Trigger mode';
            app.ZeluxTriggermodeDropDown = uidropdown(app.ZeluxSettingsPanel);
            app.ZeluxTriggermodeDropDown.Items = {'External', 'Internal'};
            app.ZeluxTriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateZeluxStatus, true);
            app.ZeluxTriggermodeDropDown.Value = 'External';
            app.ConfigureZeluxButton = uibutton(app.ZeluxSettingsPanel, 'push');
            app.ConfigureZeluxButton.ButtonPushedFcn = createCallbackFcn(app, @configureZelux, true);
            app.ConfigureZeluxButton.Text = 'Configure Zelux';
            app.CloseZeluxButton = uibutton(app.ZeluxSettingsPanel, 'push');
            app.CloseZeluxButton.ButtonPushedFcn = createCallbackFcn(app, @closeZeluxCallback, true);
            app.CloseZeluxButton.Text = 'Close Zelux';
            app.ZeluxStatusLamp = uilamp(app.ZeluxSettingsPanel);
            app.AcquisitionSettingsPanel = uipanel(app.SettingsTab);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.BackgroundshotCheckBox = uicheckbox(app.AcquisitionSettingsPanel);
            app.BackgroundshotCheckBox.Text = 'Background shot';
            app.BackgroundshotCheckBox.Value = true;
            app.DatasetsizeSpinnerLabel = uilabel(app.AcquisitionSettingsPanel);
            app.DatasetsizeSpinnerLabel.Text = 'Dataset size';
            app.DatasetsizeSpinner = uispinner(app.AcquisitionSettingsPanel);
            app.DatasetsizeSpinner.ValueDisplayFormat = '%d';
            app.DatasetsizeSpinner.Value = 20;
            app.AcquisitiontimeoutsSpinnerLabel = uilabel(app.AcquisitionSettingsPanel);
            app.AcquisitiontimeoutsSpinnerLabel.Text = 'Acquisition time out (s)';
            app.AcquisitiontimeoutsSpinner = uispinner(app.AcquisitionSettingsPanel);
            app.AcquisitiontimeoutsSpinner.Value = 1000;
            app.CamerasListBoxLabel = uilabel(app.AcquisitionSettingsPanel);
            app.CamerasListBoxLabel.Text = 'Cameras';
            app.CamerasListBox = uilistbox(app.AcquisitionSettingsPanel);
            app.CamerasListBox.Items = {'Andor 19330', 'Andor 19331', 'Zelux'};
            app.CamerasListBox.Multiselect = 'on';
            app.CamerasListBox.Value = {'Andor 19330'};
            app.AxesMenu = uicontextmenu(app.UIFigure);
            app.ToggleAutoscaleMenu = uimenu(app.AxesMenu);
            app.ToggleAutoscaleMenu.Text = 'Toggle Autoscale';
            % Assign app.AxesMenu
            app.BigAxes1.ContextMenu = app.AxesMenu;
            app.BigAxes2.ContextMenu = app.AxesMenu;
            app.SettingMenu = uicontextmenu(app.UIFigure);
            app.SetcurrentsettingsasdefaultMenu = uimenu(app.SettingMenu);
            app.SetcurrentsettingsasdefaultMenu.Text = 'Set current settings as default';
            app.ExportcurrentsettingsasMATMenu = uimenu(app.SettingMenu);
            app.ExportcurrentsettingsasMATMenu.Text = 'Export current settings as MAT';
            app.LoadsettingsfromfileMenu = uimenu(app.SettingMenu);
            app.LoadsettingsfromfileMenu.Text = 'Load settings from file';
            % Assign app.SettingMenu
            app.SettingsTab.ContextMenu = app.SettingMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageAcquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startApp)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end